classdef ProjectE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ApplyFilterButton             matlab.ui.control.Button
        MorphOperationDropdown        matlab.ui.control.DropDown
        MorphologicalOpDropDownLabel  matlab.ui.control.Label
        ThresholdSlider               matlab.ui.control.Slider
        TLabel                        matlab.ui.control.Label
        HistogramButton               matlab.ui.control.Button
        GrayscaleButton               matlab.ui.control.Button
        WeightsDropDown               matlab.ui.control.DropDown
        SelectWeightsDropDownLabel    matlab.ui.control.Label
        ContrastValueField            matlab.ui.control.Label
        CalculateContrastButton       matlab.ui.control.Button
        ProjectELabel                 matlab.ui.control.Label
        UploadImageButton             matlab.ui.control.Button
        FilteredImageAxes             matlab.ui.control.UIAxes
        BinaryImageAxes               matlab.ui.control.UIAxes
        HistogramDisplayAxes          matlab.ui.control.UIAxes
        GrayscaleDisplayAxes          matlab.ui.control.UIAxes
        ImageDisplayAxes              matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function contrast = calculateRMSContrast(app, img)
            % Ensure the image is grayscale
            if size(img, 3) == 3
                img = rgb2gray(img); % Convert RGB to grayscale if needed
            end
            
            % Convert the image to double for accurate calculations
            img = double(img);
            
            % Calculate the mean intensity
            meanIntensity = mean(img(:));
            
            % Calculate RMS contrast
            contrast = sqrt(mean((img(:) - meanIntensity).^2));
        end
        
        function grayscaleImage = customGrayscale(app, img, weights)
            % Convert the image to double for calculations
            img = double(img);
            
            % Extract the RGB channels
            R = img(:, :, 1);
            G = img(:, :, 2);
            B = img(:, :, 3);
            
            % Calculate the grayscale image using the given weights
            grayscaleImage = weights(1) * R + weights(2) * G + weights(3) * B;
            
            % Normalize the grayscale image to fit into the [0, 255] range
            grayscaleImage = uint8(grayscaleImage / max(grayscaleImage(:)) * 255);
            
        end
        
        function histValues = customHistogram(app, grayscaleImage)
        % Initialize an array to hold the histogram values
        histValues = zeros(1, 256);

        % Flatten the grayscale image to a vector
        pixels = grayscaleImage(:);

        % Count the occurrences of each intensity level
        for i = 0:255
            histValues(i + 1) = sum(pixels == i);
            
        end
        end
        %Thresholding and Binarization
        %thresholding the grayscale image:
        function binaryImage = binarizeImage(app, grayscaleImage, threshold)
            % Convert the grayscale image into a binary image
            binaryImage = grayscaleImage > threshold;
        end

        %Morphological Filtering
        %applying morphological filtering operations:
        
        function filteredImage = morphologicalFilter(app, binaryImage, operation, structElement)
            % Apply a morphological operation
            % Supported operations: 'dilate', 'erode', 'open', 'close'
            switch operation
                case 'dilate'
                    filteredImage = imdilate(binaryImage, structElement);
                case 'erode'
                    filteredImage = imerode(binaryImage, structElement);
                case 'open'
                    filteredImage = imopen(binaryImage, structElement);
                case 'close'
                    filteredImage = imclose(binaryImage, structElement);
                otherwise
                    error('Unsupported morphological operation.');
            end
        end


   end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            % Allow user to select an image file
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg', 'Image Files (*.jpg, *.png, *.jpeg)'}, 'Select an Image');
            
            % If no file is selected, show a warning
            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected!', 'Error');
                return;
            end
            
            % Read the selected image
            imagePath = fullfile(path, file);
            img = imread(imagePath);
            
            % Display the image in the axes
            imshow(img, 'Parent', app.ImageDisplayAxes);
            title(app.ImageDisplayAxes, 'Uploaded Image');
        end

        % Button pushed function: CalculateContrastButton
        function CalculateContrastButtonPushed(app, event)
             % Ensure an image is uploaded
                if isempty(app.ImageDisplayAxes.Children)
                    uialert(app.UIFigure, 'Please upload an image first!', 'Error');
                    return;
                end
            
                % Get the uploaded image from the axes
                imgHandle = app.ImageDisplayAxes.Children;
                img = get(imgHandle, 'CData');
                
                % Calculate RMS contrast
                contrast = app.calculateRMSContrast(img);
                
                % Display the contrast value in the ContrastValueField
                app.ContrastValueField.Text = sprintf('Contrast: %.2f', contrast);
        end

        % Button pushed function: GrayscaleButton
        function GrayscaleButtonPushed(app, event)
             % Ensure an image is uploaded
                if isempty(app.ImageDisplayAxes.Children)
                    uialert(app.UIFigure, 'Please upload an image first!', 'Error');
                    return;
                end
            
                % Get the uploaded image from the axes
                imgHandle = app.ImageDisplayAxes.Children;
                img = get(imgHandle, 'CData');
                
                % Get the selected weight combination from the dropdown
                selectedWeights = app.WeightsDropDown.Value;
                weights = eval(selectedWeights); % Convert string to numeric array
                
                % Convert the image to grayscale using the custom method
                grayscaleImage = app.customGrayscale(img, weights);
                
                % Display the grayscale image in the new axes
                imshow(grayscaleImage, 'Parent', app.GrayscaleDisplayAxes);
                title(app.GrayscaleDisplayAxes, sprintf('Grayscale Image (Weights: %s)', selectedWeights));
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
           % Ensure an image is uploaded
            if isempty(app.ImageDisplayAxes.Children)
                uialert(app.UIFigure, 'Please upload an image first!', 'Error');
                return;
            end
        
            % Get the grayscale image from the grayscale display
            if isempty(app.GrayscaleDisplayAxes.Children)
                uialert(app.UIFigure, 'Please convert the image to grayscale first!', 'Error');
                return;
            end
        
            % Extract the grayscale image data
            imgHandle = app.GrayscaleDisplayAxes.Children;
            grayscaleImage = get(imgHandle, 'CData');
        
            % Compute the histogram using the custom method
            histValues = app.customHistogram(grayscaleImage);
        
            % Display the histogram in the HistogramDisplayAxes
            bar(app.HistogramDisplayAxes, 0:255, histValues, 'BarWidth', 1, 'FaceColor', 'b');
            title(app.HistogramDisplayAxes, 'Histogram of Grayscale Image');
            xlabel(app.HistogramDisplayAxes, 'Pixel Intensity');
            ylabel(app.HistogramDisplayAxes, 'Frequency'); 
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
             % Ensure a grayscale image exists
            if isempty(app.GrayscaleDisplayAxes.Children)
                uialert(app.UIFigure, 'Please convert the image to grayscale first!', 'Error');
                return;
            end
        
            % Get the grayscale image
            imgHandle = app.GrayscaleDisplayAxes.Children;
            grayscaleImage = get(imgHandle, 'CData');
            
            % Get the threshold value from the slider
            threshold = round(app.ThresholdSlider.Value);
        
            % Binarize the image
            binaryImage = app.binarizeImage(grayscaleImage, threshold);
        
            % Display the binary image
            imshow(binaryImage, 'Parent', app.BinaryImageAxes);
            title(app.BinaryImageAxes, sprintf('Binary Image (Threshold: %d)', threshold));
        
            % Get the morphological operation and create a structuring element
            operation = app.MorphOperationDropdown.Value;
            structElement = strel('disk', 5); % Example: Disk-shaped structuring element with radius 5
        
            % Apply morphological filtering
            filteredImage = app.morphologicalFilter(binaryImage, operation, structElement);
        
            % Display the filtered image
            imshow(filteredImage, 'Parent', app.FilteredImageAxes);
            title(app.FilteredImageAxes, sprintf('Filtered Image (%s)', operation));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageDisplayAxes = uiaxes(app.UIFigure);
            title(app.ImageDisplayAxes, 'Image')
            xlabel(app.ImageDisplayAxes, 'X')
            ylabel(app.ImageDisplayAxes, 'Y')
            zlabel(app.ImageDisplayAxes, 'Z')
            app.GrayscaleDisplayAxes = uiaxes(app.UIFigure);
            title(app.GrayscaleDisplayAxes, 'GrayscaleDisplayAxes')
            xlabel(app.GrayscaleDisplayAxes, 'X')
            ylabel(app.GrayscaleDisplayAxes, 'Y')
            zlabel(app.GrayscaleDisplayAxes, 'Z')
            app.HistogramDisplayAxes = uiaxes(app.UIFigure);
            title(app.HistogramDisplayAxes, 'Histogram')
            xlabel(app.HistogramDisplayAxes, 'X')
            ylabel(app.HistogramDisplayAxes, 'Y')
            zlabel(app.HistogramDisplayAxes, 'Z')
            app.BinaryImageAxes = uiaxes(app.UIFigure);
            title(app.BinaryImageAxes, 'Binary Image ')
            xlabel(app.BinaryImageAxes, 'X')
            ylabel(app.BinaryImageAxes, 'Y')
            zlabel(app.BinaryImageAxes, 'Z')
            app.FilteredImageAxes = uiaxes(app.UIFigure);
            title(app.FilteredImageAxes, 'Filterd Image')
            xlabel(app.FilteredImageAxes, 'X')
            ylabel(app.FilteredImageAxes, 'Y')
            zlabel(app.FilteredImageAxes, 'Z')
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Images';
            app.ProjectELabel = uilabel(app.UIFigure);
            app.ProjectELabel.Text = 'Project E';
            app.CalculateContrastButton = uibutton(app.UIFigure, 'push');
            app.CalculateContrastButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateContrastButtonPushed, true);
            app.CalculateContrastButton.Text = 'Calculate Contrast';
            app.ContrastValueField = uilabel(app.UIFigure);
            app.ContrastValueField.Text = 'Value';
            app.SelectWeightsDropDownLabel = uilabel(app.UIFigure);
            app.SelectWeightsDropDownLabel.Text = 'Select Weights';
            app.WeightsDropDown = uidropdown(app.UIFigure);
            app.WeightsDropDown.Items = {'[0.3, 0.59, 0.11]', '[0.33, 0.33, 0.34]', '[0.4, 0.4, 0.2]', '[0.5, 0.3, 0.2]'};
            app.WeightsDropDown.Value = '[0.3, 0.59, 0.11]';
            app.GrayscaleButton = uibutton(app.UIFigure, 'push');
            app.GrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @GrayscaleButtonPushed, true);
            app.GrayscaleButton.Text = 'Convert to Grayscale';
            app.HistogramButton = uibutton(app.UIFigure, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Show Histogram';
            app.TLabel = uilabel(app.UIFigure);
            app.TLabel.Text = 'Thresholding';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 255];
            app.ThresholdSlider.Value = 128;
            app.MorphologicalOpDropDownLabel = uilabel(app.UIFigure);
            app.MorphologicalOpDropDownLabel.Text = 'Morphological  Op';
            app.MorphOperationDropdown = uidropdown(app.UIFigure);
            app.MorphOperationDropdown.Items = {'dilate', 'erode', 'open', 'close'};
            app.MorphOperationDropdown.Value = 'dilate';
            app.ApplyFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Thresholding & Filtering';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end